classdef path_create_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        LSY2EditField       matlab.ui.control.NumericEditField
        Y2EditFieldLabel    matlab.ui.control.Label
        LSX2EditField       matlab.ui.control.NumericEditField
        X2EditFieldLabel    matlab.ui.control.Label
        LSY1EditField       matlab.ui.control.NumericEditField
        Y1EditFieldLabel    matlab.ui.control.Label
        LSX1EditField       matlab.ui.control.NumericEditField
        X1EditFieldLabel    matlab.ui.control.Label
        CreateLS            matlab.ui.control.Button
        LineSegmentLabel_2  matlab.ui.control.Label
        CreatePoint         matlab.ui.control.Button
        PointYEditField     matlab.ui.control.NumericEditField
        YEditFieldLabel     matlab.ui.control.Label
        PointXEditField     matlab.ui.control.NumericEditField
        XEditFieldLabel     matlab.ui.control.Label
        PointLabel          matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Frame 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Frame = Frame;
            
        end

        % Button pushed function: CreatePoint
        function CreatePointPushed(app, event)
            x = app.PointXEditField;
            y = app.PointYEditField;
            point = Point(app.Frame, x, y);
            v = plot(app.UIAxes, point.Pos(1), point.Pos(2));
            
            % for visible vertices
            set(v, "Marker", "o", "MarkerSize", 10, "MarkerFaceColor", "b", ...
            "MarkerEdgeColor", "none");
            
            draggable(v);
        end

        % Button pushed function: CreateLS
        function CreateLSButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frame')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PointLabel = uilabel(app.UIFigure);
            app.PointLabel.Text = 'Point';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.PointXEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.PointYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CreatePoint = uibutton(app.UIFigure, 'push');
            app.CreatePoint.ButtonPushedFcn = createCallbackFcn(app, @CreatePointPushed, true);
            app.CreatePoint.Text = 'Create';
            app.LineSegmentLabel_2 = uilabel(app.UIFigure);
            app.LineSegmentLabel_2.Text = 'Line Segment';
            app.CreateLS = uibutton(app.UIFigure, 'push');
            app.CreateLS.ButtonPushedFcn = createCallbackFcn(app, @CreateLSButtonPushed, true);
            app.CreateLS.Text = 'Create';
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.LSX1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.UIFigure);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.LSY1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.LSX2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y2EditFieldLabel = uilabel(app.UIFigure);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.LSY2EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = path_create_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end